classdef winAPTmain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        MessageBox               matlab.ui.control.TextArea
        Button                   matlab.ui.control.Button
        ControlsPanel            matlab.ui.container.Panel
        TotalsDropDown           matlab.ui.control.DropDown
        TotalsDropDownLabel      matlab.ui.control.Label
        PreAmpLamp               matlab.ui.control.Lamp
        PrAmpDropDown            matlab.ui.control.DropDown
        PrAmpDropDownLabel       matlab.ui.control.Label
        IntervalsEditField       matlab.ui.control.NumericEditField
        IntervalsEditFieldLabel  matlab.ui.control.Label
        SamplesEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel    matlab.ui.control.Label
        AttenuationSpinner       matlab.ui.control.Spinner
        AttenuationSpinnerLabel  matlab.ui.control.Label
        SmoothEditField          matlab.ui.control.NumericEditField
        SmoothEditFieldLabel     matlab.ui.control.Label
        PointsEditField          matlab.ui.control.NumericEditField
        PointsEditFieldLabel     matlab.ui.control.Label
        VBWEditField             matlab.ui.control.NumericEditField
        VBWEditFieldLabel        matlab.ui.control.Label
        RBWSpinner               matlab.ui.control.Spinner
        RBWSpinnerLabel          matlab.ui.control.Label
        SpanEditField            matlab.ui.control.NumericEditField
        SpanEditFieldLabel       matlab.ui.control.Label
        CWEditField              matlab.ui.control.NumericEditField
        CWEditFieldLabel         matlab.ui.control.Label
        PRESETButton             matlab.ui.control.Button
        ExecutionPanel           matlab.ui.container.Panel
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        RunMode                  matlab.ui.control.Switch
        Button_MaxHold           matlab.ui.control.StateButton
        Button_Average           matlab.ui.control.StateButton
        Button_MinHold           matlab.ui.control.StateButton
        Plot_Panel               matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        MedidasPanel             matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.CheckBoxTree
        treeNode1                matlab.ui.container.TreeNode
        treeNode2                matlab.ui.container.TreeNode
        treeNode3                matlab.ui.container.TreeNode
        treeNode4                matlab.ui.container.TreeNode
        Panel_2                  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        Method4bDropDown         matlab.ui.control.DropDown
        Method4bDropDownLabel    matlab.ui.control.Label
        Method4aDropDown         matlab.ui.control.DropDown
        Method4aDropDownLabel    matlab.ui.control.Label
        Method3DropDown          matlab.ui.control.DropDown
        Method3DropDownLabel     matlab.ui.control.Label
        Method2DropDown          matlab.ui.control.DropDown
        Method2DropDownLabel     matlab.ui.control.Label
        Method1DropDown          matlab.ui.control.DropDown
        Method1DropDownLabel     matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        AntennaPanel             matlab.ui.container.Panel
        AntennaName              matlab.ui.control.DropDown
        Img_antena               matlab.ui.control.Image
        ReceiverPanel            matlab.ui.container.Panel
        Receiver_EditList        matlab.ui.control.Button
        Receiver_Connectivity    matlab.ui.control.Button
        Receiver_List            matlab.ui.control.DropDown
        Image                    matlab.ui.control.Image
        Img_receiver             matlab.ui.control.Image
        RightPanel               matlab.ui.container.Panel
        MeasuresPanel            matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        OccxdBTab                matlab.ui.container.Tab
        DeltaLabel               matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        EnableCheckBox           matlab.ui.control.CheckBox
        OccTab                   matlab.ui.container.Tab
        TextArea                 matlab.ui.control.TextArea
        Label_4                  matlab.ui.control.Label
        BetaLabel                matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        EnableCheckBox_3         matlab.ui.control.CheckBox
        ChPowerTab               matlab.ui.container.Tab
        ZScoreLabel              matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        EnableCheckBox_4         matlab.ui.control.CheckBox
        HarmonicsTab             matlab.ui.container.Tab
        HarmCheckBox_2           matlab.ui.control.CheckBox
        HarmCheckBox             matlab.ui.control.CheckBox
        EnableCheckBox_5         matlab.ui.control.CheckBox
        ResultsPanel             matlab.ui.container.Panel
        HTML                     matlab.ui.control.HTML
        Panel                    matlab.ui.container.Panel
        Results                  matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CallingApp
        
        receiverObj
        EB500Obj
        ctx
    end
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_ModeOperation(app)
            % app.receiverObj

            idx2 = find(app.receiverObj.List.Enable)';

            app.Receiver_List.Items = {};
            for ii = idx2
                Parameters = jsondecode(app.receiverObj.List.Parameters{ii});

                if isfield(Parameters, 'IP') && isfield(Parameters, 'Port')
                    Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                elseif isfield(Parameters, 'IP')
                    Socket = Parameters.IP;
                else
                    Socket = Parameters.Port;
                end

                app.Receiver_List.Items(end+1) = {sprintf('ID %d: %s - %s', ii, app.receiverObj.List.Name{ii}, Socket)};
            end
        end


        %-----------------------------------------------------------------%
        function [instrHandle, msgError] = ConnectivityTest_Receiver_Aux(app, MessageBoxFlag)
            instrumentName = extractBetween(app.Receiver_List.Value, ': ', ' -');

            idx1 = str2double(char(extractBetween(app.Receiver_List.Value, 'ID', ':')));
            idx2 = find(strcmp(app.receiverObj.Config.Name, instrumentName), 1);

            instrSelected = struct('Type',       app.receiverObj.List.Type{idx1},  ...
                                   'Tag',        app.receiverObj.Config.Tag{idx2}, ...
                                   'Parameters', jsondecode(app.receiverObj.List.Parameters{idx1}));

            [instrHandle, msgError] = fcn.ConnectivityTest_Receiver(app, instrSelected, MessageBoxFlag);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp  = mainApp;
            app.receiverObj = app.CallingApp.receiverObj;
            app.EB500Obj    = app.CallingApp.EB500Obj;
            app.ctx         = apt.control.CTX();

            startup_ModeOperation(app)

            % app.InstrumentbenchPanel.Title = strcat(['Instrument Bench - V ', apt.Analysers.CONSTANTS.VERSION]);
            
        end

        % Button pushed function: Button_3, Button_5, RunButton
        function ToolButtonPushed(app, event)
            switch event.Source
                case app.RunButton
                    if app.RunButton.Text == "RUN"
                        set(app.RunButton, Enable = 'off')
                        app.IntervalsEditField.Enable = "off";
                        app.SamplesEditField.Enable = 'off';
                        app.TotalsDropDown.Enable = 'off';
                        
                        pause(2); % Precessing here

                        set(app.RunButton, Enable = 'on', ...
                                           Text = "STOP", ...
                                           Icon = "LT_stop.png", ...
                                           BackgroundColor = 'white', ...
                                           FontColor = 'black');          
                    else
                        app.RunButton.Text = "RUN";
                        app.RunButton.Icon = "LT_play.png";
                        app.RunButton.BackgroundColor = 'white';
                        % app.RunButton.BackgroundColor = [0.39,0.83,0.07];
                        % app.RunButton.FontColor = 'black';
                        app.IntervalsEditField.Enable = "on";
                        app.SamplesEditField.Enable = 'on';
                        app.TotalsDropDown.Enable = 'on';
                    end

                case app.Button_3
                    % app.TextArea_results.Value = "Um número qualquer: " + string(randi(10)) + " E isso pelo load...";
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin7 = [];
            delete(app)
            
        end

        % Button pushed function: Receiver_EditList
        function Receiver_EditListButtonPushed(app, event)
            
            if isempty(app.CallingApp.auxiliarWin4)
                d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog', '');

                app.CallingApp.auxiliarWin4 = auxApp.winEditInstrumentList(app.CallingApp);
                drawnow nocallbacks

                delete(d)
            else
                figure(app.CallingApp.auxiliarWin4.UIFigure)
            end

        end

        % Button pushed function: Receiver_Connectivity
        function Receiver_ConnectivityButtonPushed(app, event)
            
            d = layoutFcn.modalWindow(app.UIFigure, 'ccTools.ProgressDialog', '');
            ConnectivityTest_Receiver_Aux(app, 1);            
            delete(d)

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            startup_ModeOperation(app)

        end

        % Value changed function: PrAmpDropDown
        function PrAmpDropDownValueChanged(app, event)
            value = app.PrAmpDropDown.Value;
            if value == "On"
                app.PreAmpLamp.Color = [0,1,0];
            else
                app.PreAmpLamp.Color = [0.65,0.65,0.65];
            end
        end

        % Value changed function: Receiver_List
        function Receiver_ListValueChanged(app, event)
            app.Receiver_List.Value;            
        end

        % Callback function
        function GPSCheckBoxValueChanged(app, event)
            if app.GPSCheckBox.Value == 0
                app.GPS_List.Enable = 'off';
                app.GPS_FixedStation.Enable = 'off';
                app.GPS_Connectivity.Enable = 'off';
            else
                app.GPS_List.Enable = 'on';
                app.GPS_FixedStation.Enable = 'on';
                app.GPS_Connectivity.Enable = 'on';
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            selectedNodes = app.Tree.SelectedNodes;
            
            switch selectedNodes
                case app.treeNode1; app.GridLayout5.RowHeight = {22,0,0,0,0};
                case app.treeNode2; app.GridLayout5.RowHeight = {0,22,0,0,0};
                case app.treeNode3; app.GridLayout5.RowHeight = {0,0,22,0,0};
                case app.treeNode4; app.GridLayout5.RowHeight = {0,0,0,22,22};
            end

        end

        % Value changed function: CWEditField
        function CWEditFieldValueChanged(app, event)
            value = app.CWEditField.Value;
            app.ctx.setup.CW.value = value;
            app.ctx.setup.CW.color = 'black';
            disp('CW Changed:')
            disp(app.ctx.setup.CW.value)

            % app.ctx.changed()
            notify(app.ctx, 'ParmChange')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {592, 592};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {829, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReceiverPanel = uipanel(app.LeftPanel);
            app.ReceiverPanel.Title = '      Receiver';
            app.Img_receiver = uiimage(app.ReceiverPanel);
            app.Img_receiver.ImageSource = 'LT_playback.png';
            app.Image = uiimage(app.ReceiverPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'LT_refresh.png';
            app.Receiver_List = uidropdown(app.ReceiverPanel);
            app.Receiver_List.Items = {};
            app.Receiver_List.ValueChangedFcn = createCallbackFcn(app, @Receiver_ListValueChanged, true);
            app.Receiver_List.Tag = 'task_Editable';
            app.Receiver_List.Value = {};
            app.Receiver_Connectivity = uibutton(app.ReceiverPanel, 'push');
            app.Receiver_Connectivity.ButtonPushedFcn = createCallbackFcn(app, @Receiver_ConnectivityButtonPushed, true);
            app.Receiver_Connectivity.Tag = 'task_Editable';
            app.Receiver_Connectivity.Icon = 'LT_connectivity.png';
            app.Receiver_Connectivity.Tooltip = {'Teste de conectividade'};
            app.Receiver_Connectivity.Text = '';
            app.Receiver_EditList = uibutton(app.ReceiverPanel, 'push');
            app.Receiver_EditList.ButtonPushedFcn = createCallbackFcn(app, @Receiver_EditListButtonPushed, true);
            app.Receiver_EditList.Tag = 'task_Editable';
            app.Receiver_EditList.Icon = 'LT_connect.png';
            app.Receiver_EditList.Tooltip = {'Editar lista de instrumentos'};
            app.Receiver_EditList.Text = '';
            app.AntennaPanel = uipanel(app.LeftPanel);
            app.AntennaPanel.Title = '      Antenna';
            app.Img_antena = uiimage(app.AntennaPanel);
            app.Img_antena.ImageSource = 'LT_antenna.png';
            app.AntennaName = uidropdown(app.AntennaPanel);
            app.AntennaName.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.AntennaName.Tag = 'task_Editable';
            app.AntennaName.Value = '';
            app.Plot_Panel = uipanel(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.Plot_Panel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Meas'; 'Label'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.MedidasPanel = uipanel(app.GridLayout3);
            app.MedidasPanel.Title = 'Medidas';
            app.MedidasPanel.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.MedidasPanel);
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.Method1DropDownLabel = uilabel(app.GridLayout5);
            app.Method1DropDownLabel.Enable = 'off';
            app.Method1DropDownLabel.Text = 'Method1:';
            app.Method1DropDown = uidropdown(app.GridLayout5);
            app.Method1DropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.Method1DropDown.Enable = 'off';
            app.Method1DropDown.Value = 'Naive';
            app.Method2DropDownLabel = uilabel(app.GridLayout5);
            app.Method2DropDownLabel.Enable = 'off';
            app.Method2DropDownLabel.Text = 'Method2:';
            app.Method2DropDown = uidropdown(app.GridLayout5);
            app.Method2DropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.Method2DropDown.Enable = 'off';
            app.Method2DropDown.Value = 'Naive';
            app.Method3DropDownLabel = uilabel(app.GridLayout5);
            app.Method3DropDownLabel.Enable = 'off';
            app.Method3DropDownLabel.Text = 'Method3:';
            app.Method3DropDown = uidropdown(app.GridLayout5);
            app.Method3DropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.Method3DropDown.Enable = 'off';
            app.Method3DropDown.Value = 'Naive';
            app.Method4aDropDownLabel = uilabel(app.GridLayout5);
            app.Method4aDropDownLabel.Enable = 'off';
            app.Method4aDropDownLabel.Text = 'Method4a:';
            app.Method4aDropDown = uidropdown(app.GridLayout5);
            app.Method4aDropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.Method4aDropDown.Enable = 'off';
            app.Method4aDropDown.Value = 'Naive';
            app.Method4bDropDownLabel = uilabel(app.GridLayout5);
            app.Method4bDropDownLabel.Enable = 'off';
            app.Method4bDropDownLabel.Text = 'Method4b:';
            app.Method4bDropDown = uidropdown(app.GridLayout5);
            app.Method4bDropDown.Items = {'Naive', 'Instrument', 'UIT'};
            app.Method4bDropDown.Enable = 'off';
            app.Method4bDropDown.Value = 'Naive';
            app.Tree = uitree(app.GridLayout4, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.treeNode1 = uitreenode(app.Tree);
            app.treeNode1.Text = 'Occ (β%)';
            app.treeNode2 = uitreenode(app.Tree);
            app.treeNode2.Text = 'Occ (xdB)';
            app.treeNode3 = uitreenode(app.Tree);
            app.treeNode3.Text = 'Channel Power';
            app.treeNode4 = uitreenode(app.Tree);
            app.treeNode4.Text = 'Harmonics';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.treeNode1];
            app.Button_MinHold = uibutton(app.LeftPanel, 'state');
            app.Button_MinHold.Text = '';
            app.Button_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.Button_Average = uibutton(app.LeftPanel, 'state');
            app.Button_Average.Text = '';
            app.Button_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.Button_MaxHold = uibutton(app.LeftPanel, 'state');
            app.Button_MaxHold.Text = '';
            app.Button_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.ControlsPanel = uipanel(app.LeftPanel);
            app.ControlsPanel.Title = 'Controls';
            app.ExecutionPanel = uipanel(app.ControlsPanel);
            app.ExecutionPanel.Title = 'Execution';
            app.RunMode = uiswitch(app.ExecutionPanel, 'slider');
            app.RunMode.Items = {'Single', 'Continuous'};
            app.RunMode.Tooltip = {'Continuous mode redo samples in moving average.'};
            app.RunMode.Value = 'Single';
            app.RunButton = uibutton(app.ExecutionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.RunButton.Icon = 'LT_play.png';
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = 'RUN';
            app.Button_2 = uibutton(app.ExecutionPanel, 'push');
            app.Button_2.Icon = 'LT_save.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Tooltip = {''};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ExecutionPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.Button_3.Icon = 'LT_OpenFile.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Tooltip = {''};
            app.Button_3.Text = '';
            app.PRESETButton = uibutton(app.ControlsPanel, 'push');
            app.PRESETButton.Icon = 'LT_task.png';
            app.PRESETButton.Tooltip = {'Proceed a Instrument Preset to default.'};
            app.PRESETButton.Text = 'PRESET';
            app.CWEditFieldLabel = uilabel(app.ControlsPanel);
            app.CWEditFieldLabel.Text = 'CW';
            app.CWEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.CWEditField.Limits = [0 Inf];
            app.CWEditField.ValueChangedFcn = createCallbackFcn(app, @CWEditFieldValueChanged, true);
            app.CWEditField.Tooltip = {'Central frequency (MHz)'};
            app.CWEditField.Value = 100.3;
            app.SpanEditFieldLabel = uilabel(app.ControlsPanel);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SpanEditField.Tooltip = {'Span (kHz)'};
            app.SpanEditField.Value = 500;
            app.RBWSpinnerLabel = uilabel(app.ControlsPanel);
            app.RBWSpinnerLabel.Text = 'RBW';
            app.RBWSpinner = uispinner(app.ControlsPanel);
            app.RBWSpinner.Step = 100;
            app.RBWSpinner.Limits = [0 Inf];
            app.RBWSpinner.Tooltip = {'Resolution Bandwidth (kHz)'};
            app.RBWSpinner.Value = 200;
            app.VBWEditFieldLabel = uilabel(app.ControlsPanel);
            app.VBWEditFieldLabel.Text = 'VBW';
            app.VBWEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.VBWEditField.Limits = [0 Inf];
            app.VBWEditField.Editable = 'off';
            app.VBWEditField.Tooltip = {'It may be a fixed value (kHz) or a ratio (ex. 1:5)'};
            app.VBWEditField.Value = 100;
            app.PointsEditFieldLabel = uilabel(app.ControlsPanel);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.PointsEditField.Limits = [1 Inf];
            app.PointsEditField.Editable = 'off';
            app.PointsEditField.Tooltip = {'Depends of the instrument capabilitie'};
            app.PointsEditField.Value = 501;
            app.SmoothEditFieldLabel = uilabel(app.ControlsPanel);
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SmoothEditField.Limits = [0 Inf];
            app.SmoothEditField.Tooltip = {'Smooth factor for the raw data samples'};
            app.SmoothEditField.Value = 0.75;
            app.AttenuationSpinnerLabel = uilabel(app.ControlsPanel);
            app.AttenuationSpinnerLabel.Text = 'Attenuation';
            app.AttenuationSpinner = uispinner(app.ControlsPanel);
            app.AttenuationSpinner.Limits = [0 100];
            app.AttenuationSpinner.Tooltip = {'Attenuation before the mixer. Use saturation test button to a better performance'};
            app.SamplesEditFieldLabel = uilabel(app.ControlsPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SamplesEditField.Limits = [1 10000];
            app.SamplesEditField.Tooltip = {'Samples for single mesure, or moving average for continuous'};
            app.SamplesEditField.Value = 20;
            app.IntervalsEditFieldLabel = uilabel(app.ControlsPanel);
            app.IntervalsEditFieldLabel.Text = 'Interval (s)';
            app.IntervalsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.IntervalsEditField.Limits = [0 Inf];
            app.IntervalsEditField.Tooltip = {'Time between continuous mesures'};
            app.IntervalsEditField.Value = 5;
            app.PrAmpDropDownLabel = uilabel(app.ControlsPanel);
            app.PrAmpDropDownLabel.Text = 'Pré-Amp';
            app.PrAmpDropDown = uidropdown(app.ControlsPanel);
            app.PrAmpDropDown.Items = {'Off', 'On'};
            app.PrAmpDropDown.ValueChangedFcn = createCallbackFcn(app, @PrAmpDropDownValueChanged, true);
            app.PrAmpDropDown.Tooltip = {'For high sensibility, it will not considered in Saturation test. Use it carefully'};
            app.PrAmpDropDown.Value = 'Off';
            app.PreAmpLamp = uilamp(app.ControlsPanel);
            app.TotalsDropDownLabel = uilabel(app.ControlsPanel);
            app.TotalsDropDownLabel.Text = 'Totals';
            app.TotalsDropDown = uidropdown(app.ControlsPanel);
            app.TotalsDropDown.Items = {'Sample', 'Accumulate'};
            app.TotalsDropDown.Value = 'Sample';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Icon = 'LT_blackX.png';
            app.Button.Text = '';
            app.MessageBox = uitextarea(app.LeftPanel);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Enable = 'off';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.Icon = 'LR_Persistance.png';
            app.Button_4.Tooltip = {'Saturation Test'};
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ToolButtonPushed, true);
            app.Button_5.Icon = 'LT_Detection.png';
            app.Button_5.Tooltip = {'Commands a instrument do do a rescale amplitude of signal'};
            app.Button_5.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Results = uihtml(app.RightPanel);
            app.Results.HTMLSource = '<html><h4>Results</h4><style>  div {    border: 3px solid blue;    padding-left: 75px;    background-color: yellow;  }  </style>  <p>Select a mesure</p></html>';
            app.Panel = uipanel(app.RightPanel);
            app.ResultsPanel = uipanel(app.RightPanel);
            app.ResultsPanel.Title = 'Results';
            app.HTML = uihtml(app.ResultsPanel);
            app.HTML.HTMLSource = '{''<html xmlns:v="urn:schemas-microsoft-com:vml" xmlns:o="urn:schemas-microsoft-com:office:office" xmlns:w="urn:schemas-microsoft-com:office:word" xmlns:m="http://schemas.microsoft.com/office/2004/12/omml" xmlns="http://www.w3.org/TR/REC-html40">  <head> <meta http-equiv=Content-Type content="text/html; charset=windows-1252"> <meta name=ProgId content=Word.Document> <meta name=Generator content="Microsoft Word 15"> <meta name=Originator content="Microsoft Word 15"> <link rel=File-List href="results_arquivos/filelist.xml"> <link rel=themeData href="results_arquivos/themedata.thmx"> <link rel=colorSchemeMapping href="results_arquivos/colorschememapping.xml"> <!--[if gte mso 9]><xml>  <w:WordDocument>   <w:Zoom>75</w:Zoom>   <w:SpellingState>Clean</w:SpellingState>   <w:GrammarState>Clean</w:GrammarState>   <w:TrackMoves>false</w:TrackMoves>   <w:TrackFormatting/>   <w:HyphenationZone>21</w:HyphenationZone>   <w:ValidateAgainstSchemas/>   <w:SaveIfXMLInvalid>false</w:SaveIfXMLInvalid>   <w:IgnoreMixedContent>false</w:IgnoreMixedContent>   <w:AlwaysShowPlaceholderText>false</w:AlwaysShowPlaceholderText>   <w:DoNotPromoteQF/>   <w:LidThemeOther>PT-BR</w:LidThemeOther>   <w:LidThemeAsian>X-NONE</w:LidThemeAsian>   <w:LidThemeComplexScript>X-NONE</w:LidThemeComplexScript>   <w:Compatibility>    <w:BreakWrappedTables/>    <w:SplitPgBreakAndParaMark/>   </w:Compatibility>   <w:BrowserLevel>MicrosoftInternetExplorer4</w:BrowserLevel>   <m:mathPr>    <m:mathFont m:val="Cambria Math"/>    <m:brkBin m:val="before"/>    <m:brkBinSub m:val="&#45;-"/>    <m:smallFrac m:val="off"/>    <m:dispDef/>    <m:lMargin m:val="0"/>    <m:rMargin m:val="0"/>    <m:defJc m:val="centerGroup"/>    <m:wrapIndent m:val="1440"/>    <m:intLim m:val="subSup"/>    <m:naryLim m:val="undOvr"/>   </m:mathPr></w:WordDocument> </xml><![endif]--><!--[if gte mso 9]><xml>  <w:LatentStyles DefLockedState="false" DefUnhideWhenUsed="false"   DefSemiHidden="false" DefQFormat="false" DefPriority="99"   LatentStyleCount="376">   <w:LsdException Locked="false" Priority="0" QFormat="true" Name="Normal"/>   <w:LsdException Locked="false" Priority="9" QFormat="true" Name="heading 1"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 2"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 3"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 4"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 5"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 6"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 7"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 8"/>   <w:LsdException Locked="false" Priority="9" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="heading 9"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 6"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 7"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 8"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index 9"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 1"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 2"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 3"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 4"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 5"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 6"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 7"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 8"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" Name="toc 9"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Normal Indent"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="footnote text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="annotation text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="header"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="footer"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="index heading"/>   <w:LsdException Locked="false" Priority="35" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="caption"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="table of figures"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="envelope address"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="envelope return"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="footnote reference"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="annotation reference"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="line number"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="page number"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="endnote reference"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="endnote text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="table of authorities"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="macro"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="toa heading"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Bullet"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Number"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Bullet 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Bullet 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Bullet 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Bullet 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Number 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Number 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Number 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Number 5"/>   <w:LsdException Locked="false" Priority="10" QFormat="true" Name="Title"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Closing"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Signature"/>   <w:LsdException Locked="false" Priority="1" SemiHidden="true"    UnhideWhenUsed="true" Name="Default Paragraph Font"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text Indent"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Continue"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Continue 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Continue 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Continue 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="List Continue 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Message Header"/>   <w:LsdException Locked="false" Priority="11" QFormat="true" Name="Subtitle"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Salutation"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Date"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text First Indent"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text First Indent 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Note Heading"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text Indent 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Body Text Indent 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Block Text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Hyperlink"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="FollowedHyperlink"/>   <w:LsdException Locked="false" Priority="22" QFormat="true" Name="Strong"/>   <w:LsdException Locked="false" Priority="20" QFormat="true" Name="Emphasis"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Document Map"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Plain Text"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="E-mail Signature"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Top of Form"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Bottom of Form"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Normal (Web)"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Acronym"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Address"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Cite"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Code"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Definition"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Keyboard"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Preformatted"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Sample"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Typewriter"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="HTML Variable"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Normal Table"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="annotation subject"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="No List"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Outline List 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Outline List 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Outline List 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Simple 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Simple 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Simple 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Classic 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Classic 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Classic 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Classic 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Colorful 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Colorful 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Colorful 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Columns 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Columns 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Columns 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Columns 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Columns 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 6"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 7"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Grid 8"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 4"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 5"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 6"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 7"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table List 8"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table 3D effects 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table 3D effects 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table 3D effects 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Contemporary"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Elegant"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Professional"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Subtle 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Subtle 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Web 1"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Web 2"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Web 3"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Balloon Text"/>   <w:LsdException Locked="false" Priority="39" Name="Table Grid"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Table Theme"/>   <w:LsdException Locked="false" SemiHidden="true" Name="Placeholder Text"/>   <w:LsdException Locked="false" Priority="1" QFormat="true" Name="No Spacing"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading"/>   <w:LsdException Locked="false" Priority="61" Name="Light List"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 1"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 1"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 1"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 1"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 1"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 1"/>   <w:LsdException Locked="false" SemiHidden="true" Name="Revision"/>   <w:LsdException Locked="false" Priority="34" QFormat="true"    Name="List Paragraph"/>   <w:LsdException Locked="false" Priority="29" QFormat="true" Name="Quote"/>   <w:LsdException Locked="false" Priority="30" QFormat="true"    Name="Intense Quote"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 1"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 1"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 1"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 1"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 1"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 1"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 1"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 1"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 2"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 2"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 2"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 2"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 2"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 2"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 2"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 2"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 2"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 2"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 2"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 2"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 2"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 2"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 3"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 3"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 3"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 3"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 3"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 3"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 3"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 3"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 3"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 3"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 3"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 3"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 3"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 3"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 4"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 4"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 4"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 4"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 4"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 4"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 4"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 4"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 4"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 4"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 4"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 4"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 4"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 4"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 5"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 5"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 5"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 5"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 5"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 5"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 5"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 5"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 5"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 5"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 5"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 5"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 5"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 5"/>   <w:LsdException Locked="false" Priority="60" Name="Light Shading Accent 6"/>   <w:LsdException Locked="false" Priority="61" Name="Light List Accent 6"/>   <w:LsdException Locked="false" Priority="62" Name="Light Grid Accent 6"/>   <w:LsdException Locked="false" Priority="63" Name="Medium Shading 1 Accent 6"/>   <w:LsdException Locked="false" Priority="64" Name="Medium Shading 2 Accent 6"/>   <w:LsdException Locked="false" Priority="65" Name="Medium List 1 Accent 6"/>   <w:LsdException Locked="false" Priority="66" Name="Medium List 2 Accent 6"/>   <w:LsdException Locked="false" Priority="67" Name="Medium Grid 1 Accent 6"/>   <w:LsdException Locked="false" Priority="68" Name="Medium Grid 2 Accent 6"/>   <w:LsdException Locked="false" Priority="69" Name="Medium Grid 3 Accent 6"/>   <w:LsdException Locked="false" Priority="70" Name="Dark List Accent 6"/>   <w:LsdException Locked="false" Priority="71" Name="Colorful Shading Accent 6"/>   <w:LsdException Locked="false" Priority="72" Name="Colorful List Accent 6"/>   <w:LsdException Locked="false" Priority="73" Name="Colorful Grid Accent 6"/>   <w:LsdException Locked="false" Priority="19" QFormat="true"    Name="Subtle Emphasis"/>   <w:LsdException Locked="false" Priority="21" QFormat="true"    Name="Intense Emphasis"/>   <w:LsdException Locked="false" Priority="31" QFormat="true"    Name="Subtle Reference"/>   <w:LsdException Locked="false" Priority="32" QFormat="true"    Name="Intense Reference"/>   <w:LsdException Locked="false" Priority="33" QFormat="true" Name="Book Title"/>   <w:LsdException Locked="false" Priority="37" SemiHidden="true"    UnhideWhenUsed="true" Name="Bibliography"/>   <w:LsdException Locked="false" Priority="39" SemiHidden="true"    UnhideWhenUsed="true" QFormat="true" Name="TOC Heading"/>   <w:LsdException Locked="false" Priority="41" Name="Plain Table 1"/>   <w:LsdException Locked="false" Priority="42" Name="Plain Table 2"/>   <w:LsdException Locked="false" Priority="43" Name="Plain Table 3"/>   <w:LsdException Locked="false" Priority="44" Name="Plain Table 4"/>   <w:LsdException Locked="false" Priority="45" Name="Plain Table 5"/>   <w:LsdException Locked="false" Priority="40" Name="Grid Table Light"/>   <w:LsdException Locked="false" Priority="46" Name="Grid Table 1 Light"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark"/>   <w:LsdException Locked="false" Priority="51" Name="Grid Table 6 Colorful"/>   <w:LsdException Locked="false" Priority="52" Name="Grid Table 7 Colorful"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 1"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 1"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 1"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 1"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 1"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 1"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 1"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 2"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 2"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 2"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 2"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 2"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 2"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 2"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 3"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 3"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 3"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 3"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 3"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 3"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 3"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 4"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 4"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 4"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 4"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 4"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 4"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 4"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 5"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 5"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 5"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 5"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 5"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 5"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 5"/>   <w:LsdException Locked="false" Priority="46"    Name="Grid Table 1 Light Accent 6"/>   <w:LsdException Locked="false" Priority="47" Name="Grid Table 2 Accent 6"/>   <w:LsdException Locked="false" Priority="48" Name="Grid Table 3 Accent 6"/>   <w:LsdException Locked="false" Priority="49" Name="Grid Table 4 Accent 6"/>   <w:LsdException Locked="false" Priority="50" Name="Grid Table 5 Dark Accent 6"/>   <w:LsdException Locked="false" Priority="51"    Name="Grid Table 6 Colorful Accent 6"/>   <w:LsdException Locked="false" Priority="52"    Name="Grid Table 7 Colorful Accent 6"/>   <w:LsdException Locked="false" Priority="46" Name="List Table 1 Light"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark"/>   <w:LsdException Locked="false" Priority="51" Name="List Table 6 Colorful"/>   <w:LsdException Locked="false" Priority="52" Name="List Table 7 Colorful"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 1"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 1"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 1"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 1"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 1"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 1"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 1"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 2"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 2"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 2"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 2"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 2"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 2"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 2"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 3"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 3"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 3"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 3"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 3"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 3"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 3"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 4"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 4"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 4"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 4"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 4"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 4"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 4"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 5"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 5"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 5"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 5"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 5"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 5"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 5"/>   <w:LsdException Locked="false" Priority="46"    Name="List Table 1 Light Accent 6"/>   <w:LsdException Locked="false" Priority="47" Name="List Table 2 Accent 6"/>   <w:LsdException Locked="false" Priority="48" Name="List Table 3 Accent 6"/>   <w:LsdException Locked="false" Priority="49" Name="List Table 4 Accent 6"/>   <w:LsdException Locked="false" Priority="50" Name="List Table 5 Dark Accent 6"/>   <w:LsdException Locked="false" Priority="51"    Name="List Table 6 Colorful Accent 6"/>   <w:LsdException Locked="false" Priority="52"    Name="List Table 7 Colorful Accent 6"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Mention"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Smart Hyperlink"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Hashtag"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Unresolved Mention"/>   <w:LsdException Locked="false" SemiHidden="true" UnhideWhenUsed="true"    Name="Smart Link"/>  </w:LatentStyles> </xml><![endif]--> <style> <!--  /* Font Definitions */  @font-face 	{font-family:"Cambria Math"; 	panose-1:2 4 5 3 5 4 6 3 2 4; 	mso-font-charset:0; 	mso-generic-font-family:roman; 	mso-font-pitch:variable; 	mso-font-signature:3 0 0 0 1 0;} @font-face 	{font-family:Calibri; 	panose-1:2 15 5 2 2 2 4 3 2 4; 	mso-font-charset:0; 	mso-generic-font-family:swiss; 	mso-font-pitch:variable; 	mso-font-signature:-469750017 -1040178053 9 0 511 0;} @font-face 	{font-family:"Calibri Light"; 	panose-1:2 15 3 2 2 2 4 3 2 4; 	mso-font-charset:0; 	mso-generic-font-family:swiss; 	mso-font-pitch:variable; 	mso-font-signature:-469750017 -1040178053 9 0 511 0;}  /* Style Definitions */  p.MsoNormal, li.MsoNormal, div.MsoNormal 	{mso-style-unhide:no; 	mso-style-qformat:yes; 	mso-style-parent:""; 	margin-top:0cm; 	margin-right:0cm; 	margin-bottom:8.0pt; 	margin-left:0cm; 	line-height:105%; 	mso-pagination:widow-orphan; 	font-size:11.0pt; 	font-family:"Calibri",sans-serif; 	mso-fareast-font-family:"Times New Roman"; 	mso-fareast-theme-font:minor-fareast;} h1 	{mso-style-priority:9; 	mso-style-unhide:no; 	mso-style-qformat:yes; 	mso-style-link:"Título 1 Char"; 	margin-top:12.0pt; 	margin-right:0cm; 	margin-bottom:0cm; 	margin-left:0cm; 	line-height:105%; 	mso-pagination:widow-orphan; 	page-break-after:avoid; 	mso-outline-level:1; 	font-size:16.0pt; 	font-family:"Calibri Light",sans-serif; 	mso-fareast-font-family:"Times New Roman"; 	mso-fareast-theme-font:minor-fareast; 	color:#2F5496; 	font-weight:normal;} span.Ttulo1Char 	{mso-style-name:"Título 1 Char"; 	mso-style-priority:9; 	mso-style-unhide:no; 	mso-style-locked:yes; 	mso-style-link:"Título 1"; 	font-family:"Calibri Light",sans-serif; 	mso-ascii-font-family:"Calibri Light"; 	mso-hansi-font-family:"Calibri Light"; 	mso-bidi-font-family:"Calibri Light"; 	color:#2F5496;} p.msonormal0, li.msonormal0, div.msonormal0 	{mso-style-name:msonormal; 	mso-style-unhide:no; 	mso-margin-top-alt:auto; 	margin-right:0cm; 	mso-margin-bottom-alt:auto; 	margin-left:0cm; 	mso-pagination:widow-orphan; 	font-size:12.0pt; 	font-family:"Times New Roman",serif; 	mso-fareast-font-family:"Times New Roman"; 	mso-fareast-theme-font:minor-fareast;} p.msochpdefault, li.msochpdefault, div.msochpdefault 	{mso-style-name:msochpdefault; 	mso-style-unhide:no; 	mso-margin-top-alt:auto; 	margin-right:0cm; 	mso-margin-bottom-alt:auto; 	margin-left:0cm; 	mso-pagination:widow-orphan; 	font-size:12.0pt; 	font-family:"Calibri",sans-serif; 	mso-fareast-font-family:"Times New Roman"; 	mso-fareast-theme-font:minor-fareast;} p.msopapdefault, li.msopapdefault, div.msopapdefault 	{mso-style-name:msopapdefault; 	mso-style-unhide:no; 	mso-margin-top-alt:auto; 	margin-right:0cm; 	margin-bottom:8.0pt; 	margin-left:0cm; 	line-height:105%; 	mso-pagination:widow-orphan; 	font-size:12.0pt; 	font-family:"Times New Roman",serif; 	mso-fareast-font-family:"Times New Roman"; 	mso-fareast-theme-font:minor-fareast;} span.SpellE 	{mso-style-name:""; 	mso-spl-e:yes;} .MsoChpDefault 	{mso-style-type:export-only; 	mso-default-props:yes; 	font-size:10.0pt; 	mso-ansi-font-size:10.0pt; 	mso-bidi-font-size:10.0pt; 	font-family:"Calibri",sans-serif; 	mso-ascii-font-family:Calibri; 	mso-hansi-font-family:Calibri; 	mso-bidi-font-family:Calibri;} @page WordSection1 	{size:595.3pt 841.9pt; 	margin:70.85pt 3.0cm 70.85pt 3.0cm; 	mso-header-margin:35.4pt; 	mso-footer-margin:35.4pt; 	mso-paper-source:0;} div.WordSection1 	{page:WordSection1;} --> </style> <!--[if gte mso 10]> <style>  /* Style Definitions */  table.MsoNormalTable 	{mso-style-name:"Tabela normal"; 	mso-tstyle-rowband-size:0; 	mso-tstyle-colband-size:0; 	mso-style-noshow:yes; 	mso-style-priority:99; 	mso-style-parent:""; 	mso-padding-alt:0cm 5.4pt 0cm 5.4pt; 	mso-para-margin:0cm; 	mso-pagination:widow-orphan; 	font-size:10.0pt; 	font-family:"Calibri",sans-serif;} </style> <![endif]--><!--[if gte mso 9]><xml>  <o:shapedefaults v:ext="edit" spidmax="1026"/> </xml><![endif]--><!--[if gte mso 9]><xml>  <o:shapelayout v:ext="edit">   <o:idmap v:ext="edit" data="1"/>  </o:shapelayout></xml><![endif]--> </head>  <body lang=PT-BR style=''tab-interval:35.4pt;word-wrap:break-word''>  <div class=WordSection1>  <p class=MsoNormal>BW por <span class=SpellE>xdB</span>:</p>  <p class=MsoNormal>(Ref. ITU Handbook 2011, pg. 255, TABLE 4.5-1)</p>  <p class=MsoNormal>&nbsp; De 99 medidas válidas, em -26 dB </p>  <p class=MsoNormal><span style=''mso-spacerun:yes''> </span>Abrindo a partir do pico:</p>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; O desvio está em Max: 248232, Min: 131344, <span class=SpellE>Avg</span>: 193659 ± 32875 Hz</p>  <p class=MsoNormal><span style=''mso-spacerun:yes''> </span>Fechando a partir das bordas:</p>  <div style=''border:none;border-bottom:solid windowtext 1.5pt;padding:0cm 0cm 1.0pt 0cm''>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; O desvio está em Max: 247232, Min: 130344, <span class=SpellE>Avg</span>: 192659 ± 32875 Hz</p>  <p class=MsoNormal>&nbsp;</p>  </div>  <p class=MsoNormal>&nbsp;</p>  <p class=MsoNormal>Frequência Central estimada para 30% do z-score:</p>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; Para 68% das medidas em 100302888 ± 45 Hz.</p>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; Para 89% das medidas em 100302888 ± 67 Hz.</p>  <div style=''border:none;border-bottom:solid windowtext 1.5pt;padding:0cm 0cm 1.0pt 0cm''>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; Para 95% das medidas em 100302888 ± 89 Hz.</p>  <p class=MsoNormal>&nbsp;</p>  </div>  <p class=MsoNormal>&nbsp;</p>  <p class=MsoNormal>Potência do Canal</p>  <div style=''border:none;border-bottom:solid windowtext 1.5pt;padding:0cm 0cm 1.0pt 0cm''>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; <span class=SpellE>Channel</span> Power -55.55 dB ± 4.11 dB (ref. unidade de entrada)</p>  <p class=MsoNormal>&nbsp;</p>  </div>  <p class=MsoNormal>&nbsp;</p>  <p class=MsoNormal>largura do canal para beta 99%:</p>  <p class=MsoNormal>&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp;&nbsp; Largura do canal com 99 amostras em 144870 ± 39120 Hz</p>  </div>  </body>  </html>''}';
            app.MeasuresPanel = uipanel(app.RightPanel);
            app.MeasuresPanel.Title = 'Measures';
            app.TabGroup = uitabgroup(app.MeasuresPanel);
            app.OccxdBTab = uitab(app.TabGroup);
            app.OccxdBTab.Title = 'Occ (xdB)';
            app.EnableCheckBox = uicheckbox(app.OccxdBTab);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox.Value = true;
            app.Label = uilabel(app.OccxdBTab);
            app.Label.Text = '';
            app.EditField = uieditfield(app.OccxdBTab, 'numeric');
            app.EditField.Value = 99;
            app.DeltaLabel = uilabel(app.OccxdBTab);
            app.DeltaLabel.Text = 'Delta (Δ%)';
            app.OccTab = uitab(app.TabGroup);
            app.OccTab.Title = 'Occ (β%)';
            app.EnableCheckBox_3 = uicheckbox(app.OccTab);
            app.EnableCheckBox_3.Text = 'Enable';
            app.EnableCheckBox_3.Value = true;
            app.EditField_3 = uieditfield(app.OccTab, 'numeric');
            app.EditField_3.Value = -26;
            app.BetaLabel = uilabel(app.OccTab);
            app.BetaLabel.Text = 'Beta (β%)';
            app.Label_4 = uilabel(app.OccTab);
            app.Label_4.Text = '';
            app.TextArea = uitextarea(app.OccTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Ref. ITU Handbook 2011, pg. 255, TABLE 4.5-1.'};
            app.ChPowerTab = uitab(app.TabGroup);
            app.ChPowerTab.Title = 'Ch. Power';
            app.EnableCheckBox_4 = uicheckbox(app.ChPowerTab);
            app.EnableCheckBox_4.Text = 'Enable';
            app.EnableCheckBox_4.Value = true;
            app.EditField_4 = uieditfield(app.ChPowerTab, 'numeric');
            app.EditField_4.Tooltip = {'Selecionados os 20% mais próximos da média.'};
            app.EditField_4.Value = 20;
            app.ZScoreLabel = uilabel(app.ChPowerTab);
            app.ZScoreLabel.Text = 'Z-Score %';
            app.HarmonicsTab = uitab(app.TabGroup);
            app.HarmonicsTab.Title = 'Harmonics';
            app.EnableCheckBox_5 = uicheckbox(app.HarmonicsTab);
            app.EnableCheckBox_5.Text = 'Enable';
            app.EnableCheckBox_5.Value = true;
            app.HarmCheckBox = uicheckbox(app.HarmonicsTab);
            app.HarmCheckBox.Text = '2ª Harm.';
            app.HarmCheckBox.Value = true;
            app.HarmCheckBox_2 = uicheckbox(app.HarmonicsTab);
            app.HarmCheckBox_2.Text = '3ª Harm';
            app.HarmCheckBox_2.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAPTmain(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end